classdef Monte_Carlo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MCInputsPanel                  matlab.ui.container.Panel
        Absorptioncoefficientam1Label  matlab.ui.control.Label
        Absorptioncoefficientam1EditField  matlab.ui.control.NumericEditField
        Isotropicscatteringcoefficientbm1EditFieldLabel  matlab.ui.control.Label
        Isotropicscatteringcoefficientbm1EditField  matlab.ui.control.NumericEditField
        PacketsperloopEditFieldLabel   matlab.ui.control.Label
        PacketsperloopEditField        matlab.ui.control.NumericEditField
        ofloopsSpinnerLabel            matlab.ui.control.Label
        ofloopsSpinner                 matlab.ui.control.Spinner
        MaxofscatteringeventsSpinnerLabel  matlab.ui.control.Label
        MaxofscatteringeventsSpinner   matlab.ui.control.Spinner
        PathlengthmaxmEditFieldLabel   matlab.ui.control.Label
        PathlengthmaxmEditField        matlab.ui.control.NumericEditField
        RunMCSimulationButton          matlab.ui.control.Button
        LambertiansemiangledegreesEditFieldLabel  matlab.ui.control.Label
        LambertiansemiangledegreesEditField  matlab.ui.control.NumericEditField
        SourceemissionprofileDropDownLabel  matlab.ui.control.Label
        SourceemissionprofileDropDown  matlab.ui.control.DropDown
        PixelpositionxywhmEditFieldLabel  matlab.ui.control.Label
        PixelpositionxywhmEditField    matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        PixelSpinnerLabel              matlab.ui.control.Label
        PixelSpinner                   matlab.ui.control.Spinner
        AddEditpixelButton             matlab.ui.control.Button
        RemovepixelButton              matlab.ui.control.Button
        PixelDimensionsLabel           matlab.ui.control.Label
        HenyeyGreensteinscatteringinputsPanel  matlab.ui.container.Panel
        Rayleighscatteringcoeffm1EditFieldLabel  matlab.ui.control.Label
        Rayleighscatteringcoeffm1EditField  matlab.ui.control.NumericEditField
        Miescatteringcoeffm1EditFieldLabel  matlab.ui.control.Label
        Miescatteringcoeffm1EditField  matlab.ui.control.NumericEditField
        gEditFieldLabel                matlab.ui.control.Label
        gEditField                     matlab.ui.control.NumericEditField
        fEditFieldLabel                matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        UpsilonLabel                   matlab.ui.control.Label
        UpsilonEditField               matlab.ui.control.NumericEditField
        ScatteringtypeDropDownLabel    matlab.ui.control.Label
        ScatteringtypeDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        a = 0.00922; % A coefficient (m^-1)
        b = 0.179; % B coefficient (m^-1)
        packets = 1e6; % # packets launched per loop
        numloops = 1; % Number of loops
        max_scatter = 10; % Maximum # of scattering events
        pmax = 10; % Maximum photon path length (m)
        sourcetype = "Lambertian"; % Source emission profile type
        semiangle = 60; % Lambertian semiangle (degrees)
        pixels = [-0.5e-3, -0.5e-3, 1e-3, 1e-3]; % Pixel location(s) (x,y,w,h), (m)
        pix_num = 1; % Pixel number, for entering pixel position values
        scatt_type = "Isotropic"; % Scattering type
        Mie = 0.284e-3; % Mie scattering coefficient
        Rayleigh = 0.266e-3; % Rayliegh scattering coefficient
        f = 0.5;
        g = 0.72;
        upsilon = 0.017;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable.Data = app.pixels;
        end

        % Value changed function: Absorptioncoefficientam1EditField
        function Absorptioncoefficientam1EditFieldValueChanged(app, event)
            value = app.Absorptioncoefficientam1EditField.Value;
            app.a = value;
        end

        % Value changed function: ofloopsSpinner
        function ofloopsSpinnerValueChanged(app, event)
            value = app.ofloopsSpinner.Value;
            app.numloops = value;
        end

        % Value changed function: 
        % Isotropicscatteringcoefficientbm1EditField
        function Isotropicscatteringcoefficientbm1EditFieldValueChanged(app, event)
            value = app.Isotropicscatteringcoefficientbm1EditField.Value;
            app.b = value;
        end

        % Value changed function: PacketsperloopEditField
        function PacketsperloopEditFieldValueChanged(app, event)
            value = app.PacketsperloopEditField.Value;
            app.packets = value;
        end

        % Value changed function: MaxofscatteringeventsSpinner
        function MaxofscatteringeventsSpinnerValueChanged(app, event)
            value = app.MaxofscatteringeventsSpinner.Value;
            app.max_scatter = value;
        end

        % Value changed function: PathlengthmaxmEditField
        function PathlengthmaxmEditFieldValueChanged(app, event)
            value = app.PathlengthmaxmEditField.Value;
            app.pmax = value;
        end

        % Value changed function: SourceemissionprofileDropDown
        function SourceemissionprofileDropDownValueChanged(app, event)
            value = app.SourceemissionprofileDropDown.Value;
            
            if value == "Lambertian"
                app.sourcetype = "Lambertian";
                app.LambertiansemiangledegreesEditField.Enable = 'on';
            elseif value == "Custom"
                app.sourcetype = "Custom";
                app.LambertiansemiangledegreesEditField.Enable = 'off';
            elseif value == "Idealised"
                app.sourcetype = "Ideal";
                app.LambertiansemiangledegreesEditField.Enable = 'off';
            end
        end

        % Value changed function: 
        % LambertiansemiangledegreesEditField
        function LambertiansemiangledegreesEditFieldValueChanged(app, event)
            value = app.LambertiansemiangledegreesEditField.Value;
            app.semiangle = value;
        end

        % Callback function
        function PixelpositionsxywhmTextAreaValueChanged(app, event)
            value = app.PixelpositionsxywhmTextArea.Value;
            app.pixels = str2double(value);
        end

        % Button pushed function: RunMCSimulationButton
        function RunMCSimulationButtonPushed(app, event)
            MC_scatter(app.a,app.b,app.packets,app.numloops,...
                app.max_scatter,app.pmax,app.sourcetype,...
                app.semiangle,app.pixels,app.scatt_type,...
                app.Rayleigh,app.Mie,app.upsilon,app.f,app.g);
        end

        % Value changed function: PixelpositionxywhmEditField
        function PixelpositionxywhmEditFieldValueChanged(app, event)
            
        end

        % Value changed function: PixelSpinner
        function PixelSpinnerValueChanged(app, event)
            value = app.PixelSpinner.Value;
            app.pix_num = value;
        end

        % Button pushed function: AddEditpixelButton
        function AddEditpixelButtonPushed(app, event)
            value = app.PixelpositionxywhmEditField.Value;
            app.pixels(app.pix_num,:) = str2num(value);
            app.UITable.Data = app.pixels;
        end

        % Button pushed function: RemovepixelButton
        function RemovepixelButtonPushed(app, event)
            app.pixels(app.pix_num,:) = [];
            app.UITable.Data = app.pixels;
        end

        % Value changed function: ScatteringtypeDropDown
        function ScatteringtypeDropDownValueChanged(app, event)
            value = app.ScatteringtypeDropDown.Value;
            
            if value == "Isotropic"
                app.scatt_type = "Isotropic";
                app.Isotropicscatteringcoefficientbm1EditField.Enable = 'on';
                app.Rayleighscatteringcoeffm1EditField.Enable = 'off';
                app.Miescatteringcoeffm1EditField.Enable = 'off';
                app.UpsilonEditField.Enable = 'off';
                app.gEditField.Enable = 'off';
                app.fEditField.Enable = 'off';
                app.Isotropicscatteringcoefficientbm1EditFieldLabel.Enable = 'on';
                app.Rayleighscatteringcoeffm1EditFieldLabel.Enable = 'off';
                app.Miescatteringcoeffm1EditFieldLabel.Enable = 'off';
                app.UpsilonLabel.Enable = 'off';
                app.gEditFieldLabel.Enable = 'off';
                app.fEditField.Enable = 'off';

            elseif value == "H-G"
                app.scatt_type = "H-G";
                app.Isotropicscatteringcoefficientbm1EditField.Enable = 'off';
                app.Rayleighscatteringcoeffm1EditField.Enable = 'on';
                app.Miescatteringcoeffm1EditField.Enable = 'on';
                app.UpsilonEditField.Enable = 'on';
                app.gEditField.Enable = 'on';
                app.fEditField.Enable = 'on';
                app.Isotropicscatteringcoefficientbm1EditFieldLabel.Enable = 'off';
                app.Rayleighscatteringcoeffm1EditFieldLabel.Enable = 'on';
                app.Miescatteringcoeffm1EditFieldLabel.Enable = 'on';
                app.UpsilonLabel.Enable = 'on';
                app.gEditFieldLabel.Enable = 'on';
                app.fEditField.Enable = 'on';
            end
        end

        % Value changed function: Rayleighscatteringcoeffm1EditField
        function Rayleighscatteringcoeffm1EditFieldValueChanged(app, event)
            value = app.Rayleighscatteringcoeffm1EditField.Value;
            app.Rayleigh = value;
        end

        % Value changed function: Miescatteringcoeffm1EditField
        function Miescatteringcoeffm1EditFieldValueChanged(app, event)
            value = app.Miescatteringcoeffm1EditField.Value;
            app.Mie = value;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.UpsilonEditField.Value;
            app.upsilon = value;
        end

        % Value changed function: gEditField
        function gEditFieldValueChanged(app, event)
            value = app.gEditField.Value;
            app.g = value;
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            value = app.fEditField.Value;
            app.f = value;
        end

        % Value changed function: UpsilonEditField
        function UpsilonEditFieldValueChanged(app, event)
            value = app.UpsilonEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MCInputsPanel = uipanel(app.UIFigure);
            app.MCInputsPanel.Title = 'MC Inputs';
            app.Absorptioncoefficientam1Label = uilabel(app.MCInputsPanel);
            app.Absorptioncoefficientam1Label.Text = 'Absorption coefficient, a, (m^-1)';
            app.Absorptioncoefficientam1EditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.Absorptioncoefficientam1EditField.Limits = [0 Inf];
            app.Absorptioncoefficientam1EditField.ValueChangedFcn = createCallbackFcn(app, @Absorptioncoefficientam1EditFieldValueChanged, true);
            app.Absorptioncoefficientam1EditField.Value = 0.00922;
            app.Isotropicscatteringcoefficientbm1EditFieldLabel = uilabel(app.MCInputsPanel);
            app.Isotropicscatteringcoefficientbm1EditFieldLabel.Text = 'Isotropic scattering coefficient, b, (m^-1)';
            app.Isotropicscatteringcoefficientbm1EditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.Isotropicscatteringcoefficientbm1EditField.Limits = [0 Inf];
            app.Isotropicscatteringcoefficientbm1EditField.ValueChangedFcn = createCallbackFcn(app, @Isotropicscatteringcoefficientbm1EditFieldValueChanged, true);
            app.Isotropicscatteringcoefficientbm1EditField.Value = 0.179;
            app.PacketsperloopEditFieldLabel = uilabel(app.MCInputsPanel);
            app.PacketsperloopEditFieldLabel.Text = '# Packets per loop';
            app.PacketsperloopEditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.PacketsperloopEditField.Limits = [1 Inf];
            app.PacketsperloopEditField.ValueChangedFcn = createCallbackFcn(app, @PacketsperloopEditFieldValueChanged, true);
            app.PacketsperloopEditField.Value = 1000000;
            app.ofloopsSpinnerLabel = uilabel(app.MCInputsPanel);
            app.ofloopsSpinnerLabel.Text = '# of loops';
            app.ofloopsSpinner = uispinner(app.MCInputsPanel);
            app.ofloopsSpinner.Limits = [1 Inf];
            app.ofloopsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofloopsSpinnerValueChanged, true);
            app.ofloopsSpinner.Value = 1;
            app.MaxofscatteringeventsSpinnerLabel = uilabel(app.MCInputsPanel);
            app.MaxofscatteringeventsSpinnerLabel.Text = 'Max. # of scattering events';
            app.MaxofscatteringeventsSpinner = uispinner(app.MCInputsPanel);
            app.MaxofscatteringeventsSpinner.Limits = [2 Inf];
            app.MaxofscatteringeventsSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxofscatteringeventsSpinnerValueChanged, true);
            app.MaxofscatteringeventsSpinner.Value = 10;
            app.PathlengthmaxmEditFieldLabel = uilabel(app.MCInputsPanel);
            app.PathlengthmaxmEditFieldLabel.Text = 'Path length max. (m)';
            app.PathlengthmaxmEditField = uieditfield(app.MCInputsPanel, 'numeric');
            app.PathlengthmaxmEditField.ValueChangedFcn = createCallbackFcn(app, @PathlengthmaxmEditFieldValueChanged, true);
            app.PathlengthmaxmEditField.Value = 10;
            app.RunMCSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunMCSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunMCSimulationButtonPushed, true);
            app.RunMCSimulationButton.Text = 'Run MC Simulation';
            app.LambertiansemiangledegreesEditFieldLabel = uilabel(app.UIFigure);
            app.LambertiansemiangledegreesEditFieldLabel.Text = 'Lambertian semiangle (degrees)';
            app.LambertiansemiangledegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambertiansemiangledegreesEditField.Limits = [0 90];
            app.LambertiansemiangledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @LambertiansemiangledegreesEditFieldValueChanged, true);
            app.LambertiansemiangledegreesEditField.Value = 60;
            app.SourceemissionprofileDropDownLabel = uilabel(app.UIFigure);
            app.SourceemissionprofileDropDownLabel.Text = 'Source emission profile';
            app.SourceemissionprofileDropDown = uidropdown(app.UIFigure);
            app.SourceemissionprofileDropDown.Items = {'Idealised', 'Lambertian', 'Custom'};
            app.SourceemissionprofileDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceemissionprofileDropDownValueChanged, true);
            app.SourceemissionprofileDropDown.Value = 'Lambertian';
            app.PixelpositionxywhmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelpositionxywhmEditFieldLabel.Text = 'Pixel position (x,y,w,h), (m)';
            app.PixelpositionxywhmEditField = uieditfield(app.UIFigure, 'text');
            app.PixelpositionxywhmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelpositionxywhmEditFieldValueChanged, true);
            app.PixelpositionxywhmEditField.Value = '-0.5e-3, -0.5e-3, 1e-3, 1e-3;';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x (m)'; 'y (m)'; 'Width (m)'; 'Height (m)'};
            app.UITable.RowName = {};
            app.PixelSpinnerLabel = uilabel(app.UIFigure);
            app.PixelSpinnerLabel.Text = 'Pixel';
            app.PixelSpinner = uispinner(app.UIFigure);
            app.PixelSpinner.Limits = [1 Inf];
            app.PixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelSpinnerValueChanged, true);
            app.PixelSpinner.Value = 1;
            app.AddEditpixelButton = uibutton(app.UIFigure, 'push');
            app.AddEditpixelButton.ButtonPushedFcn = createCallbackFcn(app, @AddEditpixelButtonPushed, true);
            app.AddEditpixelButton.Text = 'Add/Edit pixel';
            app.RemovepixelButton = uibutton(app.UIFigure, 'push');
            app.RemovepixelButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepixelButtonPushed, true);
            app.RemovepixelButton.Text = 'Remove pixel';
            app.PixelDimensionsLabel = uilabel(app.UIFigure);
            app.PixelDimensionsLabel.Text = 'Pixel Dimensions';
            app.HenyeyGreensteinscatteringinputsPanel = uipanel(app.UIFigure);
            app.HenyeyGreensteinscatteringinputsPanel.Title = 'Henyey-Greenstein scattering inputs';
            app.Rayleighscatteringcoeffm1EditFieldLabel = uilabel(app.HenyeyGreensteinscatteringinputsPanel);
            app.Rayleighscatteringcoeffm1EditFieldLabel.Enable = 'off';
            app.Rayleighscatteringcoeffm1EditFieldLabel.Text = 'Rayleigh scattering coeff. (m^-1)';
            app.Rayleighscatteringcoeffm1EditField = uieditfield(app.HenyeyGreensteinscatteringinputsPanel, 'numeric');
            app.Rayleighscatteringcoeffm1EditField.ValueChangedFcn = createCallbackFcn(app, @Rayleighscatteringcoeffm1EditFieldValueChanged, true);
            app.Rayleighscatteringcoeffm1EditField.Enable = 'off';
            app.Rayleighscatteringcoeffm1EditField.Value = 0.000266;
            app.Miescatteringcoeffm1EditFieldLabel = uilabel(app.HenyeyGreensteinscatteringinputsPanel);
            app.Miescatteringcoeffm1EditFieldLabel.Enable = 'off';
            app.Miescatteringcoeffm1EditFieldLabel.Text = 'Mie scattering coeff. (m^-1)';
            app.Miescatteringcoeffm1EditField = uieditfield(app.HenyeyGreensteinscatteringinputsPanel, 'numeric');
            app.Miescatteringcoeffm1EditField.ValueChangedFcn = createCallbackFcn(app, @Miescatteringcoeffm1EditFieldValueChanged, true);
            app.Miescatteringcoeffm1EditField.Enable = 'off';
            app.Miescatteringcoeffm1EditField.Value = 0.000284;
            app.gEditFieldLabel = uilabel(app.HenyeyGreensteinscatteringinputsPanel);
            app.gEditFieldLabel.Enable = 'off';
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.HenyeyGreensteinscatteringinputsPanel, 'numeric');
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @gEditFieldValueChanged, true);
            app.gEditField.Enable = 'off';
            app.gEditField.Value = 0.72;
            app.fEditFieldLabel = uilabel(app.HenyeyGreensteinscatteringinputsPanel);
            app.fEditFieldLabel.Enable = 'off';
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.HenyeyGreensteinscatteringinputsPanel, 'numeric');
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.fEditField.Enable = 'off';
            app.fEditField.Value = 0.5;
            app.UpsilonLabel = uilabel(app.HenyeyGreensteinscatteringinputsPanel);
            app.UpsilonLabel.Enable = 'off';
            app.UpsilonLabel.Text = 'Upsilon (ϒ)';
            app.UpsilonEditField = uieditfield(app.HenyeyGreensteinscatteringinputsPanel, 'numeric');
            app.UpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @UpsilonEditFieldValueChanged, true);
            app.UpsilonEditField.Enable = 'off';
            app.UpsilonEditField.Value = 0.017;
            app.ScatteringtypeDropDownLabel = uilabel(app.UIFigure);
            app.ScatteringtypeDropDownLabel.Text = 'Scattering type';
            app.ScatteringtypeDropDown = uidropdown(app.UIFigure);
            app.ScatteringtypeDropDown.Items = {'Isotropic', 'H-G'};
            app.ScatteringtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScatteringtypeDropDownValueChanged, true);
            app.ScatteringtypeDropDown.Value = 'Isotropic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monte_Carlo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end