classdef Monte_Carlo_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadScatteringSimulationDataButton  matlab.ui.control.Button
        TargetDefinitionPanel          matlab.ui.container.Panel
        TargetPlaneDropDownLabel       matlab.ui.control.Label
        TargetPlaneDropDown            matlab.ui.control.DropDown
        DistancetoTargetmEditFieldLabel  matlab.ui.control.Label
        DistancetoTargetmEditField     matlab.ui.control.NumericEditField
        TargetCentreCoordinatesmEditFieldLabel  matlab.ui.control.Label
        TargetCentreCoordinatesmEditField  matlab.ui.control.EditField
        TargetWidthmEditFieldLabel     matlab.ui.control.Label
        TargetWidthmEditField          matlab.ui.control.NumericEditField
        NumberofgridsperrowcolSpinnerLabel  matlab.ui.control.Label
        NumberofgridsperrowcolSpinner  matlab.ui.control.Spinner
        Gridcoordinatesx1x2y1y2z1z2mEditFieldLabel  matlab.ui.control.Label
        Gridcoordinatesx1x2y1y2z1z2mEditField  matlab.ui.control.EditField
        CalculateButton                matlab.ui.control.Button
        OutputfilenameEditFieldLabel   matlab.ui.control.Label
        OutputfilenameEditField        matlab.ui.control.EditField
        SavedoutputsDropDownLabel      matlab.ui.control.Label
        SavedoutputsDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        filename
        output_filename = 'output.mat'
        trgt_plane = 'z' % Target plane (x, y or z)
        trgt_dist = 1; % Distance to target (m)
        trgt_centre = [0,0]; % Centre of target (m)
        trgt_width = 1e-3; % Width of target (m)
        %grid_width = 1; % Grid target total width (m)
        num_grid = 100; % Number of grids per row/col
        grid_corners = [0,0,0,0,0,0]
        %grid_step = 0.05; % Grid step size (m)
        
        coordinates % Calculated coordinates of photon packet 'hits'
        hitweights % Corresponding hit weights
        
        savetype = 'Results only' % Save type (results only or all including packets)
        save_yn = 'no';
        
        % OUTPUTS %
        Rx_received_total = 0;
        xGrid
        yGrid
        zGrid
        weightMatrix
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadScatteringSimulationDataButton
        function LoadScatteringSimulationDataButtonPushed(app, event)
            app.filename = uigetfile({'*.mat'},'MultiSelect','on'); % Get file names
        end

        % Value changed function: TargetPlaneDropDown
        function TargetPlaneDropDownValueChanged(app, event)
            value = app.TargetPlaneDropDown.Value;
            app.trgt_plane = value;
        end

        % Value changed function: DistancetoTargetmEditField
        function DistancetoTargetmEditFieldValueChanged(app, event)
            value = app.DistancetoTargetmEditField.Value;
            app.trgt_dist = value;
        end

        % Callback function
        function Gridcoordinatesx1x2y1y2z1z2mEditFieldValueChanged(app, event)
            value = app.Gridcoordinatesx1x2y1y2z1z2mEditField.Value;
            app.grid_width = value;
        end

        % Callback function
        function GridStepSizemEditFieldValueChanged(app, event)
            value = app.GridStepSizemEditField.Value;
            app.grid_step = value;
        end

        % Value changed function: TargetCentreCoordinatesmEditField
        function TargetCentreCoordinatesmEditFieldValueChanged(app, event)
            value = app.TargetCentreCoordinatesmEditField.Value;
            app.trgt_centre = str2num(value);
        end

        % Value changed function: TargetWidthmEditField
        function TargetWidthmEditFieldValueChanged(app, event)
            value = app.TargetWidthmEditField.Value;
            app.trgt_width = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           f = waitbar(0,'Analysing data'); % Open the waitbar
            
           for n = 1:length(app.filename)
            file = cell2mat(app.filename(n));
            [app.coordinates,app.hitweights] = photon_packet_analysis(file,app.trgt_plane,app.trgt_dist); % Find packets that intercept the target plane

            if n == length(app.filename)
                app.save_yn = 'yes';
            else
                app.save_yn = 'no';
            end
            
            [app.Rx_received_total,app.xGrid,app.yGrid,app.zGrid,app.weightMatrix]...
                = target_plane_analysis(app.coordinates,app.hitweights,...
                app.grid_corners,app.trgt_plane,...
                app.num_grid,app.Rx_received_total,app.weightMatrix,...
                app.savetype,app.save_yn,app.output_filename); % Analyse these packets
            
            waitbar(n/length(app.filename),f)
           end
        
           close(f); % CLose the waitbar
           g = msgbox('Analysis complete.');
        end

        % Value changed function: OutputfilenameEditField
        function OutputfilenameEditFieldValueChanged(app, event)
            value = app.OutputfilenameEditField.Value;
            app.output_filename = horzcat(value,'.mat');
        end

        % Value changed function: SavedoutputsDropDown
        function SavedoutputsDropDownValueChanged(app, event)
            value = app.SavedoutputsDropDown.Value;
            app.savetype = value;
        end

        % Value changed function: NumberofgridsperrowcolSpinner
        function NumberofgridsperrowcolSpinnerValueChanged(app, event)
            value = app.NumberofgridsperrowcolSpinner.Value;
            app.num_grid = value;
        end

        % Value changed function: 
        % Gridcoordinatesx1x2y1y2z1z2mEditField
        function Gridcoordinatesx1x2y1y2z1z2mEditFieldValueChanged2(app, event)
            value = app.Gridcoordinatesx1x2y1y2z1z2mEditField.Value;
            app.grid_corners = str2num(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadScatteringSimulationDataButton = uibutton(app.UIFigure, 'push');
            app.LoadScatteringSimulationDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScatteringSimulationDataButtonPushed, true);
            app.LoadScatteringSimulationDataButton.Text = 'Load Scattering Simulation Data';
            app.TargetDefinitionPanel = uipanel(app.UIFigure);
            app.TargetDefinitionPanel.Title = 'Target Definition';
            app.TargetPlaneDropDownLabel = uilabel(app.TargetDefinitionPanel);
            app.TargetPlaneDropDownLabel.Text = 'Target Plane';
            app.TargetPlaneDropDown = uidropdown(app.TargetDefinitionPanel);
            app.TargetPlaneDropDown.Items = {'x', 'y', 'z'};
            app.TargetPlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetPlaneDropDownValueChanged, true);
            app.TargetPlaneDropDown.Value = 'z';
            app.DistancetoTargetmEditFieldLabel = uilabel(app.TargetDefinitionPanel);
            app.DistancetoTargetmEditFieldLabel.Text = 'Distance to Target (m)';
            app.DistancetoTargetmEditField = uieditfield(app.TargetDefinitionPanel, 'numeric');
            app.DistancetoTargetmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancetoTargetmEditFieldValueChanged, true);
            app.DistancetoTargetmEditField.Value = 1;
            app.TargetCentreCoordinatesmEditFieldLabel = uilabel(app.TargetDefinitionPanel);
            app.TargetCentreCoordinatesmEditFieldLabel.Text = 'Target Centre Coordinates (m)';
            app.TargetCentreCoordinatesmEditField = uieditfield(app.TargetDefinitionPanel, 'text');
            app.TargetCentreCoordinatesmEditField.ValueChangedFcn = createCallbackFcn(app, @TargetCentreCoordinatesmEditFieldValueChanged, true);
            app.TargetCentreCoordinatesmEditField.Value = '0,0;';
            app.TargetWidthmEditFieldLabel = uilabel(app.TargetDefinitionPanel);
            app.TargetWidthmEditFieldLabel.Text = 'Target Width (m)';
            app.TargetWidthmEditField = uieditfield(app.TargetDefinitionPanel, 'numeric');
            app.TargetWidthmEditField.Limits = [0 Inf];
            app.TargetWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @TargetWidthmEditFieldValueChanged, true);
            app.TargetWidthmEditField.Value = 0.001;
            app.NumberofgridsperrowcolSpinnerLabel = uilabel(app.TargetDefinitionPanel);
            app.NumberofgridsperrowcolSpinnerLabel.Text = 'Number of grids per row/col';
            app.NumberofgridsperrowcolSpinner = uispinner(app.TargetDefinitionPanel);
            app.NumberofgridsperrowcolSpinner.Limits = [1 Inf];
            app.NumberofgridsperrowcolSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofgridsperrowcolSpinnerValueChanged, true);
            app.NumberofgridsperrowcolSpinner.Value = 100;
            app.Gridcoordinatesx1x2y1y2z1z2mEditFieldLabel = uilabel(app.TargetDefinitionPanel);
            app.Gridcoordinatesx1x2y1y2z1z2mEditFieldLabel.Text = {'Grid coordinates'; '(x1,x2,y1,y2,z1,z2), (m)'};
            app.Gridcoordinatesx1x2y1y2z1z2mEditField = uieditfield(app.TargetDefinitionPanel, 'text');
            app.Gridcoordinatesx1x2y1y2z1z2mEditField.ValueChangedFcn = createCallbackFcn(app, @Gridcoordinatesx1x2y1y2z1z2mEditFieldValueChanged2, true);
            app.Gridcoordinatesx1x2y1y2z1z2mEditField.Value = '0,0,0,0,0,0;';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputfilenameEditFieldLabel.Text = 'Output filename';
            app.OutputfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfilenameEditFieldValueChanged, true);
            app.OutputfilenameEditField.Value = 'output';
            app.SavedoutputsDropDownLabel = uilabel(app.UIFigure);
            app.SavedoutputsDropDownLabel.Text = 'Saved outputs';
            app.SavedoutputsDropDown = uidropdown(app.UIFigure);
            app.SavedoutputsDropDown.Items = {'Results only', 'All'};
            app.SavedoutputsDropDown.ValueChangedFcn = createCallbackFcn(app, @SavedoutputsDropDownValueChanged, true);
            app.SavedoutputsDropDown.Value = 'Results only';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monte_Carlo_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end